classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DatasetButtonGroup              matlab.ui.container.ButtonGroup
        Patient1Button                  matlab.ui.control.RadioButton
        Patient2Button                  matlab.ui.control.RadioButton
        NetworkStatusPanel              matlab.ui.container.Panel
        BuildingLampLabel               matlab.ui.control.Label
        BuildingLamp                    matlab.ui.control.Lamp
        TrainingLampLabel               matlab.ui.control.Label
        TrainingLamp                    matlab.ui.control.Lamp
        TestingLampLabel                matlab.ui.control.Label
        TestingLamp                     matlab.ui.control.Lamp
        MultilayeredNetworkPanel        matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PredictButton                   matlab.ui.control.RadioButton
        DetectButton                    matlab.ui.control.RadioButton
        TrainDropDownLabel              matlab.ui.control.Label
        TrainDropDown                   matlab.ui.control.DropDown
        TRAINButton                     matlab.ui.control.Button
        NumberofneuronsDropDownLabel    matlab.ui.control.Label
        NumberofneuronsDropDown         matlab.ui.control.DropDown
        NumberofFeaturesDropDownLabel   matlab.ui.control.Label
        NumberofFeaturesDropDown        matlab.ui.control.DropDown
        ClassificationTypeDropDownLabel  matlab.ui.control.Label
        ClassificationTypeDropDown      matlab.ui.control.DropDown
        NumberofClassesDropDownLabel    matlab.ui.control.Label
        NumberofClassesDropDown         matlab.ui.control.DropDown
        ClassBalancingDropDownLabel     matlab.ui.control.Label
        ClassBalancingDropDown          matlab.ui.control.DropDown
        PredictionResultsPanel          matlab.ui.container.Panel
        SensitivityLabel                matlab.ui.control.Label
        SpecificityLabel                matlab.ui.control.Label
        LabelSpecificity                matlab.ui.control.Label
        LabelSensitivity                matlab.ui.control.Label
        TruePositivesLabel              matlab.ui.control.Label
        LabelTP                         matlab.ui.control.Label
        TrueNegativeLabel               matlab.ui.control.Label
        LabelTN                         matlab.ui.control.Label
        FalsePositivesLabel             matlab.ui.control.Label
        LabelFP                         matlab.ui.control.Label
        FalseNegativesLabel             matlab.ui.control.Label
        LabelFN                         matlab.ui.control.Label
        AccuracyLabel_2                 matlab.ui.control.Label
        AccuracyPrediction              matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        DetectionResultsPanel           matlab.ui.container.Panel
        Lamp_2                          matlab.ui.control.Lamp
        SensitivityLabel_2              matlab.ui.control.Label
        SpecificityLabel_2              matlab.ui.control.Label
        LabelSpecificity_2              matlab.ui.control.Label
        LabelSensitivity_2              matlab.ui.control.Label
        TruePositivesLabel_2            matlab.ui.control.Label
        LabelTP_2                       matlab.ui.control.Label
        TrueNegativeLabel_2             matlab.ui.control.Label
        LabelTN_2                       matlab.ui.control.Label
        FalsePositivesLabel_2           matlab.ui.control.Label
        LabelFP_2                       matlab.ui.control.Label
        FalseNegativesLabel_2           matlab.ui.control.Label
        LabelFN_2                       matlab.ui.control.Label
        AccuracyLabel_3                 matlab.ui.control.Label
        AccuracyDetection               matlab.ui.control.Label
        TestNeuralNetworkPanel          matlab.ui.container.Panel
        TESTButton                      matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        ClassificationTypeDropDown_2Label  matlab.ui.control.Label
        ClassificationTypeDropDown_2    matlab.ui.control.DropDown
        DeepLearningNetworkPanel        matlab.ui.container.Panel
        NetworkTypeDropDown_3Label      matlab.ui.control.Label
        DeepNetworkType                 matlab.ui.control.DropDown
        TRAINButton_2                   matlab.ui.control.Button
        ClassificationTypeDropDown_5Label  matlab.ui.control.Label
        ClassificationTypeDropDown_5    matlab.ui.control.DropDown
        RecurrentNetworkPanel           matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        PredictButton_2                 matlab.ui.control.RadioButton
        DetectButton_2                  matlab.ui.control.RadioButton
        TrainDropDown_2Label            matlab.ui.control.Label
        TrainDropDown_2                 matlab.ui.control.DropDown
        TRAINButton_3                   matlab.ui.control.Button
        NumberofneuronsDropDown_2Label  matlab.ui.control.Label
        NumberofneuronsDropDown_2       matlab.ui.control.DropDown
        NumberofFeaturesDropDown_2Label  matlab.ui.control.Label
        NumberofFeaturesDropDown_2      matlab.ui.control.DropDown
        ClassificationTypeDropDown_3Label  matlab.ui.control.Label
        ClassificationTypeDropDown_3    matlab.ui.control.DropDown
        NumberofClassesDropDown_2Label  matlab.ui.control.Label
        NumberofClassesDropDown_2       matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function results = reset_lights(app)
            app.BuildingLamp.Color = "black";
            app.TrainingLamp.Color = "black";
            app.TestingLamp.Color = "black";
            app.Lamp.Color = "black";
            app.Lamp_2.Color = "black";
        end
        
        function update_results(app, prediction_results, detection_results)
            % Prediction
            app.LabelSensitivity.Text = num2str(prediction_results.sensitivity);
            app.LabelSpecificity.Text = num2str(prediction_results.specificity);
            app.AccuracyPrediction.Text = num2str(prediction_results.accuracy);
            app.LabelTP.Text = num2str(prediction_results.true_positives);
            app.LabelTN.Text = num2str(prediction_results.true_negatives);
            app.LabelFP.Text = num2str(prediction_results.false_positives);
            app.LabelFN.Text = num2str(prediction_results.false_negatives);
            
            % Detection 
            app.LabelSensitivity_2.Text = num2str(detection_results.sensitivity);
            app.LabelSpecificity_2.Text = num2str(detection_results.specificity);
            app.LabelTP_2.Text = num2str(detection_results.true_positives);
            app.LabelTN_2.Text = num2str(detection_results.true_negatives);
            app.LabelFP_2.Text = num2str(detection_results.false_positives);
            app.LabelFN_2.Text = num2str(detection_results.false_negatives);
            app.AccuracyDetection.Text = num2str(detection_results.accuracy);
        end
        
        function list_files(app)
            
            trained_nnets = dir('TrainedNNetworks'); % Directory
            names = strings;    
            
            for i = 3 : length(trained_nnets)
                name = trained_nnets(i, 1).name;
                names(i - 2, 1) = name;
            end     
            app.DropDown.Items = names;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            list_files(app);
        end

        % Button pushed function: TRAINButton
        function TRAINButtonPushed(app, event)
            reset_lights(app)
            
            dataset = app.DatasetButtonGroup.SelectedObject.Text;
            type = 'Multilayer';
            goal = app.ButtonGroup.SelectedObject.Text;
            train_ratio = str2double(app.TrainDropDown.Value);
            neurons = str2double(app.NumberofneuronsDropDown.Value);
            features = str2double(app.NumberofFeaturesDropDown.Value);
            classification = app.ClassificationTypeDropDown.Value;
            classes = str2double(app.NumberofClassesDropDown.Value);
            class_balancing = app.ClassBalancingDropDown.Value;
            spec = "On";
            
            if strcmp(dataset, "Patient 1")
                dataset = '1';
            else
                dataset = '2';
            end
            
            if strcmp(goal, "Predict")
                is_prediction = true;
            else
                is_prediction = false;
            end
            
            if strcmp(class_balancing, 'On')
               class_balancing = 1;
            else
                class_balancing = 0;
            end
            
            % ============ BUILD THE DATASET ===========================
            app.BuildingLamp.Color = 'yellow';
            [P_train, P_test, T_train, T_test] = build_dataset(dataset, features, train_ratio, classes, class_balancing);
            app.BuildingLamp.Color = 'green';


            % ============ TRAINING NETWORK ============================
            app.TrainingLamp.Color = 'yellow';
            nn = training(P_train, T_train, type, neurons, is_prediction, spec, class_balancing);
            app.TrainingLamp.Color = 'green';
            
            % ============ TEST NETWORK ================================
            app.TestingLamp.Color = "yellow";
            [prediction, detection] = testing(nn, P_test, T_test, classification, type);
            app.TestingLamp.Color = "green";
            
            % ============ UPDATE RESULT SECTION =======================
            update_results(app, prediction, detection);
            
            if is_prediction == true
                app.Lamp.Color = "green";
            else
                app.Lamp_2.Color = "green";
            end
            
            % =========== SAVE NETWORK =================================
            
            save_network(nn, P_test, T_test, dataset, type, is_prediction, class_balancing, train_ratio, features, neurons);
            list_files(app);
            
        end

        % Button pushed function: TRAINButton_3
        function TRAINButton_3Pushed(app, event)
            reset_lights(app)
            dataset = app.DatasetButtonGroup.SelectedObject.Text;
            
            if strcmp(dataset, "Patient 1")
                dataset = '1';
            else
                dataset = '2';
            end
            
            goal = app.ButtonGroup_2.SelectedObject.Text;
            if strcmp(goal, "Predict")
                is_prediction = true;
            else
                is_prediction = false;
            end
            type = 'Recurrent';
            train_ratio = str2double(app.TrainDropDown_2.Value);
            neurons = str2double(app.NumberofneuronsDropDown_2.Value);
            features = str2double(app.NumberofFeaturesDropDown_2.Value);
            classification = app.ClassificationTypeDropDown_2.Value;
            classes = str2double(app.NumberofClassesDropDown_2.Value);
            
            % ============ BUILD THE DATASET ===========================
            app.BuildingLamp.Color = 'yellow';
            [P_train, P_test, T_train, T_test] = build_dataset(dataset, features, train_ratio, classes, 0);
            app.BuildingLamp.Color = 'green';
            
            % ============ TRAINING NETWORK ============================
            app.TrainingLamp.Color = 'yellow';
            nn = training(P_train, T_train, type, neurons, is_prediction, "None", "On");
            app.TrainingLamp.Color = 'green';
            
            % ============ TEST NETWORK ================================
            app.TestingLamp.Color = "yellow";
            [prediction, detection] = testing(nn, P_test, T_test, classification, type);
            app.TestingLamp.Color = "green";
            
            % ============ UPDATE RESULT SECTION =======================
            update_results(app, prediction, detection);
            
            if is_prediction == true
                app.Lamp.Color = "green";
            else
                app.Lamp_2.Color = "green";
            end
            
            % =========== SAVE NETWORK =================================
            
            save_network(nn, P_test, T_test, dataset, type, is_prediction, 0, train_ratio, features, neurons);
            list_files(app);
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            filename = app.DropDown.Value;
            
            split_text = split(filename, "_");
            type = split_text{3};
            type = type(2: length(type));
            
            features = split_text{8};
            features = str2double(features(2: length(features) - 3));
            
            classes = 3;
            
            reset_lights(app);
            
            dataset = app.DatasetButtonGroup.SelectedObject.Text;
            classification = app.ClassificationTypeDropDown_2.Value;
            
            if strcmp(dataset, "Patient 1")
                dataset = '1';
            else
                dataset = '2';
            end
            
            nn_file = strcat("TrainedNNetworks\", filename);
            data = load(nn_file);
            
            app.BuildingLamp.Color = 'yellow';
            [P_train, P_test, T_train, T_test] = build_dataset(dataset, features, 0, classes, 0);
            app.BuildingLamp.Color = 'green';
            
            % ============ TEST NETWORK ================================
            app.TestingLamp.Color = "yellow";
            [prediction, detection] = testing(data.nn, P_test, T_test, classification, type);
            app.TestingLamp.Color = "green";
            
            % ============ UPDATE RESULT SECTION =======================
            update_results(app, prediction, detection);
            app.Lamp.Color = "green";
            app.Lamp_2.Color = "green";

        end

        % Button pushed function: TRAINButton_2
        function TRAINButton_2Pushed(app, event)
            
            dataset = app.DatasetButtonGroup.SelectedObject.Text;
            
            if strcmp(dataset, "Patient 1")
                dataset = '1';
            else
                dataset = '2';
            end
            
            features = 29;
            train_ratio = 0.9999;
            classes = 3;
            neurons = 50;
            type = app.DeepNetworkType.Value;
            is_prediction = true;
            classification = app.ClassificationTypeDropDown_5.Value;
            
            % ============ BUILD THE DATASET ===========================
            app.BuildingLamp.Color = 'yellow';
            [P_train, P_test, T_train, T_test] = build_dataset(dataset, features, train_ratio, classes, 0);
            app.BuildingLamp.Color = 'green';
            
            % ============ TRAINING NETWORK ============================
            app.TrainingLamp.Color = 'yellow';
            nn = training(P_train, T_train, type, neurons, is_prediction, "None", "On");
            app.TrainingLamp.Color = 'green';
            
            % ============ TEST NETWORK ================================
            app.TestingLamp.Color = "yellow";
            [prediction, detection] = testing(nn, P_test, T_test, classification, type);
            app.TestingLamp.Color = "green";
            
            % ============ UPDATE RESULT SECTION =======================
            update_results(app, prediction, detection);
            app.Lamp.Color = "green";
            app.Lamp_2.Color = "green";
            
            % =========== SAVE NETWORK =================================
            save_network(nn, P_test, T_test, dataset, type, is_prediction, 0, train_ratio, features, neurons);
            list_files(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DatasetButtonGroup = uibuttongroup(app.UIFigure);
            app.DatasetButtonGroup.Title = 'Dataset';
            app.Patient1Button = uiradiobutton(app.DatasetButtonGroup);
            app.Patient1Button.Text = 'Patient 1';
            app.Patient1Button.Value = true;
            app.Patient2Button = uiradiobutton(app.DatasetButtonGroup);
            app.Patient2Button.Text = 'Patient 2';
            app.NetworkStatusPanel = uipanel(app.UIFigure);
            app.NetworkStatusPanel.Title = 'Network Status';
            app.BuildingLampLabel = uilabel(app.NetworkStatusPanel);
            app.BuildingLampLabel.Text = 'Building';
            app.BuildingLamp = uilamp(app.NetworkStatusPanel);
            app.TrainingLampLabel = uilabel(app.NetworkStatusPanel);
            app.TrainingLampLabel.Text = 'Training';
            app.TrainingLamp = uilamp(app.NetworkStatusPanel);
            app.TestingLampLabel = uilabel(app.NetworkStatusPanel);
            app.TestingLampLabel.Text = 'Testing';
            app.TestingLamp = uilamp(app.NetworkStatusPanel);
            app.MultilayeredNetworkPanel = uipanel(app.UIFigure);
            app.MultilayeredNetworkPanel.Title = 'Multilayered Network';
            app.ButtonGroup = uibuttongroup(app.MultilayeredNetworkPanel);
            app.PredictButton = uiradiobutton(app.ButtonGroup);
            app.PredictButton.Text = 'Predict';
            app.PredictButton.Value = true;
            app.DetectButton = uiradiobutton(app.ButtonGroup);
            app.DetectButton.Text = 'Detect';
            app.TrainDropDownLabel = uilabel(app.MultilayeredNetworkPanel);
            app.TrainDropDownLabel.Text = 'Train %';
            app.TrainDropDown = uidropdown(app.MultilayeredNetworkPanel);
            app.TrainDropDown.Items = {'0.7', '0.8', '0.9'};
            app.TrainDropDown.Value = '0.7';
            app.TRAINButton = uibutton(app.MultilayeredNetworkPanel, 'push');
            app.TRAINButton.ButtonPushedFcn = createCallbackFcn(app, @TRAINButtonPushed, true);
            app.TRAINButton.Text = 'TRAIN';
            app.NumberofneuronsDropDownLabel = uilabel(app.MultilayeredNetworkPanel);
            app.NumberofneuronsDropDownLabel.Text = 'Number of neurons';
            app.NumberofneuronsDropDown = uidropdown(app.MultilayeredNetworkPanel);
            app.NumberofneuronsDropDown.Items = {'10', '20', '30', '40', '50', '100'};
            app.NumberofneuronsDropDown.Value = '10';
            app.NumberofFeaturesDropDownLabel = uilabel(app.MultilayeredNetworkPanel);
            app.NumberofFeaturesDropDownLabel.Text = 'Number of Features';
            app.NumberofFeaturesDropDown = uidropdown(app.MultilayeredNetworkPanel);
            app.NumberofFeaturesDropDown.Items = {'3', '15', '20', '29'};
            app.NumberofFeaturesDropDown.Value = '29';
            app.ClassificationTypeDropDownLabel = uilabel(app.MultilayeredNetworkPanel);
            app.ClassificationTypeDropDownLabel.Text = 'Classification Type';
            app.ClassificationTypeDropDown = uidropdown(app.MultilayeredNetworkPanel);
            app.ClassificationTypeDropDown.Items = {'Single', 'Grouped'};
            app.ClassificationTypeDropDown.Value = 'Single';
            app.NumberofClassesDropDownLabel = uilabel(app.MultilayeredNetworkPanel);
            app.NumberofClassesDropDownLabel.Text = 'Number of Classes';
            app.NumberofClassesDropDown = uidropdown(app.MultilayeredNetworkPanel);
            app.NumberofClassesDropDown.Items = {'3', '2'};
            app.NumberofClassesDropDown.Value = '3';
            app.ClassBalancingDropDownLabel = uilabel(app.MultilayeredNetworkPanel);
            app.ClassBalancingDropDownLabel.Text = 'Class Balancing';
            app.ClassBalancingDropDown = uidropdown(app.MultilayeredNetworkPanel);
            app.ClassBalancingDropDown.Items = {'Off', 'On'};
            app.ClassBalancingDropDown.Value = 'Off';
            app.PredictionResultsPanel = uipanel(app.UIFigure);
            app.PredictionResultsPanel.Title = 'Prediction Results';
            app.SensitivityLabel = uilabel(app.PredictionResultsPanel);
            app.SensitivityLabel.Text = 'Sensitivity:';
            app.SpecificityLabel = uilabel(app.PredictionResultsPanel);
            app.SpecificityLabel.Text = 'Specificity:';
            app.LabelSpecificity = uilabel(app.PredictionResultsPanel);
            app.LabelSpecificity.Text = '0';
            app.LabelSensitivity = uilabel(app.PredictionResultsPanel);
            app.LabelSensitivity.Text = '0';
            app.TruePositivesLabel = uilabel(app.PredictionResultsPanel);
            app.TruePositivesLabel.Text = 'True Positives:';
            app.LabelTP = uilabel(app.PredictionResultsPanel);
            app.LabelTP.Text = '0';
            app.TrueNegativeLabel = uilabel(app.PredictionResultsPanel);
            app.TrueNegativeLabel.Text = 'True Negative:';
            app.LabelTN = uilabel(app.PredictionResultsPanel);
            app.LabelTN.Text = '0';
            app.FalsePositivesLabel = uilabel(app.PredictionResultsPanel);
            app.FalsePositivesLabel.Text = 'False Positives:';
            app.LabelFP = uilabel(app.PredictionResultsPanel);
            app.LabelFP.Text = '0';
            app.FalseNegativesLabel = uilabel(app.PredictionResultsPanel);
            app.FalseNegativesLabel.Text = 'False Negatives:';
            app.LabelFN = uilabel(app.PredictionResultsPanel);
            app.LabelFN.Text = '0';
            app.AccuracyLabel_2 = uilabel(app.PredictionResultsPanel);
            app.AccuracyLabel_2.Text = 'Accuracy:';
            app.AccuracyPrediction = uilabel(app.PredictionResultsPanel);
            app.AccuracyPrediction.Text = '0';
            app.Lamp = uilamp(app.PredictionResultsPanel);
            app.DetectionResultsPanel = uipanel(app.UIFigure);
            app.DetectionResultsPanel.Title = 'Detection Results';
            app.Lamp_2 = uilamp(app.DetectionResultsPanel);
            app.SensitivityLabel_2 = uilabel(app.DetectionResultsPanel);
            app.SensitivityLabel_2.Text = 'Sensitivity:';
            app.SpecificityLabel_2 = uilabel(app.DetectionResultsPanel);
            app.SpecificityLabel_2.Text = 'Specificity:';
            app.LabelSpecificity_2 = uilabel(app.DetectionResultsPanel);
            app.LabelSpecificity_2.Text = '0';
            app.LabelSensitivity_2 = uilabel(app.DetectionResultsPanel);
            app.LabelSensitivity_2.Text = '0';
            app.TruePositivesLabel_2 = uilabel(app.DetectionResultsPanel);
            app.TruePositivesLabel_2.Text = 'True Positives:';
            app.LabelTP_2 = uilabel(app.DetectionResultsPanel);
            app.LabelTP_2.Text = '0';
            app.TrueNegativeLabel_2 = uilabel(app.DetectionResultsPanel);
            app.TrueNegativeLabel_2.Text = 'True Negative:';
            app.LabelTN_2 = uilabel(app.DetectionResultsPanel);
            app.LabelTN_2.Text = '0';
            app.FalsePositivesLabel_2 = uilabel(app.DetectionResultsPanel);
            app.FalsePositivesLabel_2.Text = 'False Positives:';
            app.LabelFP_2 = uilabel(app.DetectionResultsPanel);
            app.LabelFP_2.Text = '0';
            app.FalseNegativesLabel_2 = uilabel(app.DetectionResultsPanel);
            app.FalseNegativesLabel_2.Text = 'False Negatives:';
            app.LabelFN_2 = uilabel(app.DetectionResultsPanel);
            app.LabelFN_2.Text = '0';
            app.AccuracyLabel_3 = uilabel(app.DetectionResultsPanel);
            app.AccuracyLabel_3.Text = 'Accuracy:';
            app.AccuracyDetection = uilabel(app.DetectionResultsPanel);
            app.AccuracyDetection.Text = '0';
            app.TestNeuralNetworkPanel = uipanel(app.UIFigure);
            app.TestNeuralNetworkPanel.Title = 'Test  Neural Network';
            app.TESTButton = uibutton(app.TestNeuralNetworkPanel, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.DropDown = uidropdown(app.TestNeuralNetworkPanel);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.ClassificationTypeDropDown_2Label = uilabel(app.TestNeuralNetworkPanel);
            app.ClassificationTypeDropDown_2Label.Text = 'Classification Type';
            app.ClassificationTypeDropDown_2 = uidropdown(app.TestNeuralNetworkPanel);
            app.ClassificationTypeDropDown_2.Items = {'Single', 'Grouped'};
            app.ClassificationTypeDropDown_2.Value = 'Single';
            app.DeepLearningNetworkPanel = uipanel(app.UIFigure);
            app.DeepLearningNetworkPanel.Title = 'Deep Learning Network';
            app.NetworkTypeDropDown_3Label = uilabel(app.DeepLearningNetworkPanel);
            app.NetworkTypeDropDown_3Label.Text = 'Network Type';
            app.DeepNetworkType = uidropdown(app.DeepLearningNetworkPanel);
            app.DeepNetworkType.Items = {'CNN', 'LSTM'};
            app.DeepNetworkType.Value = 'LSTM';
            app.TRAINButton_2 = uibutton(app.DeepLearningNetworkPanel, 'push');
            app.TRAINButton_2.ButtonPushedFcn = createCallbackFcn(app, @TRAINButton_2Pushed, true);
            app.TRAINButton_2.Text = 'TRAIN';
            app.ClassificationTypeDropDown_5Label = uilabel(app.DeepLearningNetworkPanel);
            app.ClassificationTypeDropDown_5Label.Text = 'Classification Type';
            app.ClassificationTypeDropDown_5 = uidropdown(app.DeepLearningNetworkPanel);
            app.ClassificationTypeDropDown_5.Items = {'Single', 'Grouped'};
            app.ClassificationTypeDropDown_5.Value = 'Single';
            app.RecurrentNetworkPanel = uipanel(app.UIFigure);
            app.RecurrentNetworkPanel.Title = 'Recurrent Network';
            app.ButtonGroup_2 = uibuttongroup(app.RecurrentNetworkPanel);
            app.PredictButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.PredictButton_2.Text = 'Predict';
            app.PredictButton_2.Value = true;
            app.DetectButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.DetectButton_2.Text = 'Detect';
            app.TrainDropDown_2Label = uilabel(app.RecurrentNetworkPanel);
            app.TrainDropDown_2Label.Text = 'Train %';
            app.TrainDropDown_2 = uidropdown(app.RecurrentNetworkPanel);
            app.TrainDropDown_2.Items = {'0.7', '0.8', '0.9'};
            app.TrainDropDown_2.Value = '0.7';
            app.TRAINButton_3 = uibutton(app.RecurrentNetworkPanel, 'push');
            app.TRAINButton_3.ButtonPushedFcn = createCallbackFcn(app, @TRAINButton_3Pushed, true);
            app.TRAINButton_3.Text = 'TRAIN';
            app.NumberofneuronsDropDown_2Label = uilabel(app.RecurrentNetworkPanel);
            app.NumberofneuronsDropDown_2Label.Text = 'Number of neurons';
            app.NumberofneuronsDropDown_2 = uidropdown(app.RecurrentNetworkPanel);
            app.NumberofneuronsDropDown_2.Items = {'10', '20', '30', '40', '50', '100'};
            app.NumberofneuronsDropDown_2.Value = '10';
            app.NumberofFeaturesDropDown_2Label = uilabel(app.RecurrentNetworkPanel);
            app.NumberofFeaturesDropDown_2Label.Text = 'Number of Features';
            app.NumberofFeaturesDropDown_2 = uidropdown(app.RecurrentNetworkPanel);
            app.NumberofFeaturesDropDown_2.Items = {'3', '15', '20', '29'};
            app.NumberofFeaturesDropDown_2.Value = '29';
            app.ClassificationTypeDropDown_3Label = uilabel(app.RecurrentNetworkPanel);
            app.ClassificationTypeDropDown_3Label.Text = 'Classification Type';
            app.ClassificationTypeDropDown_3 = uidropdown(app.RecurrentNetworkPanel);
            app.ClassificationTypeDropDown_3.Items = {'Single', 'Grouped'};
            app.ClassificationTypeDropDown_3.Value = 'Single';
            app.NumberofClassesDropDown_2Label = uilabel(app.RecurrentNetworkPanel);
            app.NumberofClassesDropDown_2Label.Text = 'Number of Classes';
            app.NumberofClassesDropDown_2 = uidropdown(app.RecurrentNetworkPanel);
            app.NumberofClassesDropDown_2.Items = {'3', '2'};
            app.NumberofClassesDropDown_2.Value = '3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end